classdef CNMFe_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ChangeallvaluesLabel  matlab.ui.control.Label
        FramerateLabel        matlab.ui.control.Label
        gSigLabel             matlab.ui.control.Label
        CnLabel               matlab.ui.control.Label
        PNRLabel              matlab.ui.control.Label
        sf_allset             matlab.ui.control.NumericEditField
        gSig_allset           matlab.ui.control.NumericEditField
        cn_allset             matlab.ui.control.NumericEditField
        pnr_allset            matlab.ui.control.NumericEditField
        DoneButton            matlab.ui.control.Button
        LoadDataButton        matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end


    properties (Access = public)
        theFiles % Files listed in the table
        cnmf     % CNMF-E settings of each file, including the seed mask drawn with Get
    end

    methods (Access = public)

        function update_tabe(app,ix,val)
            % Write val into column ix for every file in the table.
            if isempty(app.UITable.Data)
                return
            end
            app.UITable.Data{:,ix} = repmat(val, height(app.UITable.Data), 1);
        end

        function cnmf = update_gSig_dependents(~, cnmf, old_gSig)
            % gSiz, ring_radius and min_pixel are derived from gSig when the
            % parameters are created (CNMFE_parameters). Derive them again from
            % the gSig set here, so extraction does not run with values that
            % belong to the previous gSig. A min_pixel set by hand (one that
            % does not match the value derived from the previous gSig) is kept.
            given = struct('gSig', cnmf.gSig);
            if isfield(cnmf, 'bg_neuron_factor') && ~isempty(cnmf.bg_neuron_factor)
                given.bg_neuron_factor = cnmf.bg_neuron_factor;
            end
            new_pars = CNMFE_parameters(given);
            cnmf.gSiz = new_pars.gSiz;
            cnmf.ring_radius = new_pars.ring_radius;

            min_pixel_was_derived = ~isfield(cnmf, 'min_pixel') || isempty(cnmf.min_pixel) || isempty(old_gSig);
            if ~min_pixel_was_derived
                given.gSig = old_gSig;
                old_pars = CNMFE_parameters(given);
                min_pixel_was_derived = isequal(cnmf.min_pixel, old_pars.min_pixel);
            end
            if min_pixel_was_derived
                cnmf.min_pixel = new_pars.min_pixel;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % The frame rate belongs to the recording and is set once for the
            % whole pipeline (CaliAli_parameters('sf', ...)). This app does not
            % change it, so its box is hidden.
            app.sf_allset.Visible = 'off';
            app.FramerateLabel.Visible = 'off';

            % Only the thresholds and gSig can be edited in the table.
            app.UITable.ColumnName = {'File'; 'PNR'; 'Corr'; 'gSig'; 'Get'};
            app.UITable.ColumnEditable = [false true true true false];

            app.UIFigure.Name = 'CaliAli: initialization parameters';
            tip = 'Type a value and press Enter to apply it to every file in the table';
            app.pnr_allset.Tooltip = tip;
            app.cn_allset.Tooltip = tip;
            app.gSig_allset.Tooltip = tip;
            app.LoadDataButton.Tooltip = 'Choose the _det or _Aligned .mat files to set up';
            app.DoneButton.Tooltip = 'Save the values shown in the table to each file and close';
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            files = uipickfiles('REFilter','det|aligned');
            if ~iscell(files) || isempty(files)
                return % file selection was cancelled
            end
            File = files(:);
            n = numel(File);
            app.theFiles = File';
            app.cnmf = cell(1, n);
            PNR = zeros(n, 1);
            Corr = zeros(n, 1);
            gSig = zeros(n, 1);
            Get = repmat({'Press me'}, n, 1);
            for i = 1:n
                app.cnmf{i} = CaliAli_load(File{i},'CaliAli_options.cnmf');
                PNR(i) = double(app.cnmf{i}.min_pnr);
                Corr(i) = double(app.cnmf{i}.min_corr);
                gSig(i) = double(app.cnmf{i}.gSig(1));
            end
            app.UITable.Data = table(File, PNR, Corr, gSig, Get);
            removeStyle(app.UITable);
            s = uistyle('BackgroundColor','green');
            addStyle(app.UITable, s, 'column', 5);

            app.pnr_allset.Editable = 'on';
            app.pnr_allset.Enable = 'on';

            app.cn_allset.Editable = 'on';
            app.cn_allset.Enable = 'on';

            app.gSig_allset.Editable = 'on';
            app.gSig_allset.Enable = 'on';

            app.UITable.Enable = 'on';
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if isempty(indices) || size(indices, 2) < 2 || indices(1, 2) ~= 5
                return % only the Get column opens the threshold window
            end
            row = indices(1, 1);
            % Clear the selection so Get can be pressed again on the same row.
            app.UITable.Selection = [];

            d = app.UITable.Data;
            param_in = {d.File{row}, d.PNR(row), d.Corr(row), d.gSig(row)};
            app.cnmf{row} = estimate_PNR_Coor_Thr(param_in, app.cnmf{row}.seed_mask);
            app.UITable.Data.PNR(row) = app.cnmf{row}.min_pnr;
            app.UITable.Data.Corr(row) = app.cnmf{row}.min_corr;
            app.UITable.Data.gSig(row) = app.cnmf{row}.gSig;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            d = app.UITable.Data;
            if isempty(d)
                fprintf('No files were loaded, so nothing was saved.\n');
                delete(app)
                return
            end
            app.UIFigure.WindowState = 'minimized';
            for i = 1:height(d)
                file = d.File{i};
                CaliAli_options = CaliAli_load(file,'CaliAli_options');
                old_gSig = CaliAli_options.cnmf.gSig;

                % The table holds what is saved: values set with Get, typed in a
                % cell, or applied to every file with the boxes above the table.
                CaliAli_options.cnmf.min_pnr = d.PNR(i);
                CaliAli_options.cnmf.min_corr = d.Corr(i);
                CaliAli_options.cnmf.gSig = d.gSig(i);
                CaliAli_options.cnmf.seed_mask = app.cnmf{i}.seed_mask;
                CaliAli_options.cnmf = update_gSig_dependents(app, CaliAli_options.cnmf, old_gSig);

                if CaliAli_options.inter_session_alignment.gSig~=CaliAli_options.cnmf.gSig
                    cprintf('*red', 'Warning for file: %s.\n', file);
                    cprintf('red','The neuron size used for calculating projections (gSig = %.1f) does not match the neuron size specified for CNMF extraction (%.1f).\n', ...
                        CaliAli_options.inter_session_alignment.gSig, CaliAli_options.cnmf.gSig);
                    cprintf('-comment', 'Recalculating projections (and re-alignment) is not required, but it is recommended.\n');
                end

                cprintf('black', 'CNMF options updated for %s\n', file)
                CaliAli_save(file, 'CaliAli_options', CaliAli_options);
            end
            delete(app)
        end

        % Value changed function: pnr_allset
        function pnr_allsetValueChanged(app, event)
            val = app.pnr_allset.Value;
            update_tabe(app,2,val)

        end

        % Value changed function: cn_allset
        function cn_allsetValueChanged(app, event)
            val = app.cn_allset.Value;
            update_tabe(app,3,val)

        end

        % Value changed function: gSig_allset
        function gSig_allsetValueChanged(app, event)
            val = app.gSig_allset.Value;
            update_tabe(app,4,val)

        end

        % Value changed function: sf_allset
        function sf_allsetValueChanged(app, event)
            % The frame-rate box is hidden (see startupFcn): the frame rate is
            % not set in this app.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'File'; 'PNR'; 'Corr'; 'gSig'; 'Frame rate'; 'Get'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Enable = 'off';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done!';
            app.pnr_allset = uieditfield(app.UIFigure, 'numeric');
            app.pnr_allset.ValueChangedFcn = createCallbackFcn(app, @pnr_allsetValueChanged, true);
            app.pnr_allset.Editable = 'off';
            app.pnr_allset.Enable = 'off';
            app.pnr_allset.Value = 7;
            app.cn_allset = uieditfield(app.UIFigure, 'numeric');
            app.cn_allset.ValueChangedFcn = createCallbackFcn(app, @cn_allsetValueChanged, true);
            app.cn_allset.Editable = 'off';
            app.cn_allset.Enable = 'off';
            app.cn_allset.Value = 0.5;
            app.gSig_allset = uieditfield(app.UIFigure, 'numeric');
            app.gSig_allset.ValueChangedFcn = createCallbackFcn(app, @gSig_allsetValueChanged, true);
            app.gSig_allset.Editable = 'off';
            app.gSig_allset.Enable = 'off';
            app.gSig_allset.Value = 2.5;
            app.sf_allset = uieditfield(app.UIFigure, 'numeric');
            app.sf_allset.ValueChangedFcn = createCallbackFcn(app, @sf_allsetValueChanged, true);
            app.sf_allset.Editable = 'off';
            app.sf_allset.Enable = 'off';
            app.sf_allset.Value = 10;
            app.PNRLabel = uilabel(app.UIFigure);
            app.PNRLabel.Text = 'PNR';
            app.CnLabel = uilabel(app.UIFigure);
            app.CnLabel.Text = 'Corr';
            app.gSigLabel = uilabel(app.UIFigure);
            app.gSigLabel.Text = 'gSig';
            app.FramerateLabel = uilabel(app.UIFigure);
            app.FramerateLabel.Text = 'Frame rate';
            app.ChangeallvaluesLabel = uilabel(app.UIFigure);
            app.ChangeallvaluesLabel.Text = 'Change all values';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CNMFe_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end