classdef estimate_Corr_PNR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        corr_slider       matlab.ui.control.Slider
        pnr_slider        matlab.ui.control.Slider
        NeuronsizeLabel   matlab.ui.control.Label
        gSigSpinner       matlab.ui.control.Spinner
        gSigSpinnerLabel  matlab.ui.control.Label
        DrawmaskinthecorrelationprojectionLabel  matlab.ui.control.Label
        ApplyMaskButton   matlab.ui.control.Button
        DrawMaskButton    matlab.ui.control.Button
        OkButton          matlab.ui.control.Button
        corrSpinner       matlab.ui.control.Spinner
        corrSpinnerLabel  matlab.ui.control.Label
        PNRSpinner        matlab.ui.control.Spinner
        PNRSpinnerLabel   matlab.ui.control.Label
        pnrcnAxes         matlab.ui.control.UIAxes
        cnAxes            matlab.ui.control.UIAxes
        pnrAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ind % Description
        tmp_ind
        min_corr
        min_pnr
        gSig
        pnr_vmax
        pnr
        cn
        mask
        done
        ROI
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cn, pnr, gSig, min_corr, min_pnr, mask)
            % Initialize variable
            app.gSig=gSig;
            app.done=0;
            app.cn=cn;
            app.mask=logical(mask);
            app.pnr=pnr;
            app.corrSpinner.Value=min_corr;
            app.PNRSpinner.Value=min_pnr;
            app.gSigSpinner.Value=gSig;
            tmp_d = max(1,round(app.gSig));
            v_max = ordfilt2(app.cn.*app.pnr, tmp_d^2, true(tmp_d));
            app.pnr_vmax =  median(pnr(mask),'all','omitnan')+median(pnr(mask),'all','omitnan')/0.6745;
            app.ind = (v_max==cn.*pnr);
            axis(app.cnAxes, 'tight')
            axis(app.pnrAxes, 'tight')
            axis(app.pnrcnAxes, 'tight')
            app.pnr_slider.Limits=[1,double(max(pnr,[],'all'))];
            app.corr_slider.Limits=[0.05,1];
            app.pnr_slider.Value = double(app.pnr_vmax);
            app.corr_slider.Value = 1;
            imagesc(app.cn,'parent',app.cnAxes);hold(app.cnAxes, 'on');colorbar(app.cnAxes);clim(app.cnAxes,[0 1]);colormap(app.cnAxes,'parula');
            imagesc(app.pnr,'parent',app.pnrAxes);hold(app.pnrAxes, 'on');colorbar(app.pnrAxes);clim(app.pnrAxes,[0 app.pnr_vmax]);colormap(app.pnrAxes,'parula');
            imagesc(app.cn.*app.pnr,'parent',app.pnrcnAxes);hold(app.pnrcnAxes, 'on');colorbar(app.pnrcnAxes);clim(app.pnrcnAxes,[0 app.corr_slider.Value*app.pnr_slider.Value]);colormap(app.pnrcnAxes,'parula');
            
            
            cn_ind = app.ind & (app.cn>=app.corrSpinner.Value & app.mask) ;
            [r1, c1] = find(cn_ind);
            plot(c1, r1, '.r', 'markersize', 10,'parent',app.cnAxes);
            
            
            pnr_ind = app.ind & (app.pnr>=app.PNRSpinner.Value & app.mask);
            [r2, c2] = find(pnr_ind);
            plot(c2, r2, '.r', 'markersize', 10,'parent',app.pnrAxes);
            
            app.tmp_ind = cn_ind & pnr_ind;
            [r, c] = find(app.tmp_ind);
            plot(c, r, '.m', 'markersize', 10,'parent',app.pnrcnAxes);
            
            
        end

        % Value changed function: PNRSpinner
        function PNRSpinnerValueChanged(app, event)
            cla(app.cnAxes);cla(app.pnrAxes);cla(app.pnrcnAxes);
            
            imagesc(app.cn,'parent',app.cnAxes);hold(app.cnAxes, 'on');colorbar(app.cnAxes);
            imagesc(app.pnr,'parent',app.pnrAxes);hold(app.pnrAxes, 'on');colorbar(app.pnrAxes);
            imagesc(app.cn.*app.pnr,'parent',app.pnrcnAxes);hold(app.pnrcnAxes, 'on');colorbar(app.pnrcnAxes);
            
            
            cn_ind = app.ind & (app.cn>=app.corrSpinner.Value & app.mask) ;
            [r1, c1] = find(cn_ind);
            plot(c1, r1, '.r', 'markersize', 10,'parent',app.cnAxes);
            
            
            pnr_ind = app.ind & (app.pnr>=app.PNRSpinner.Value & app.mask);
            [r2, c2] = find(pnr_ind);
            plot(c2, r2, '.r', 'markersize', 10,'parent',app.pnrAxes);
            
            app.tmp_ind = cn_ind & pnr_ind;
            [r, c] = find(app.tmp_ind);
            plot(c, r, '.m', 'markersize', 10,'parent',app.pnrcnAxes);


        end

        % Value changed function: corrSpinner
        function corrSpinnerValueChanged(app, event)
            cla(app.cnAxes);cla(app.pnrAxes);cla(app.pnrcnAxes);
            
            imagesc(app.cn,'parent',app.cnAxes);hold(app.cnAxes, 'on');colorbar(app.cnAxes);
            imagesc(app.pnr,'parent',app.pnrAxes);hold(app.pnrAxes, 'on');colorbar(app.pnrAxes);
            imagesc(app.cn.*app.pnr,'parent',app.pnrcnAxes);hold(app.pnrcnAxes, 'on');colorbar(app.pnrcnAxes);
            
            
            cn_ind = app.ind & (app.cn>=app.corrSpinner.Value & app.mask) ;
            [r1, c1] = find(cn_ind);
            plot(c1, r1, '.r', 'markersize', 10,'parent',app.cnAxes);
            
            
            pnr_ind = app.ind & (app.pnr>=app.PNRSpinner.Value & app.mask);
            [r2, c2] = find(pnr_ind);
            plot(c2, r2, '.r', 'markersize', 10,'parent',app.pnrAxes);
            
            app.tmp_ind = cn_ind & pnr_ind;
            [r, c] = find(app.tmp_ind);
            plot(c, r, '.m', 'markersize', 10,'parent',app.pnrcnAxes);
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.done=1;
            
        end

        % Button pushed function: DrawMaskButton
        function DrawMaskButtonPushed(app, event)
            temp = drawpolygon(app.cnAxes);
            app.ROI = createMask(temp);
            if (sum(app.ROI,'all')>0)
                app.ApplyMaskButton.Enable = 'on';
            end
        end

        % Button pushed function: ApplyMaskButton
        function ApplyMaskButtonPushed(app, event)
            app.mask=app.ROI;
            
            cla(app.cnAxes);cla(app.pnrAxes);cla(app.pnrcnAxes);
            
            imagesc(app.cn,'parent',app.cnAxes);hold(app.cnAxes, 'on');colorbar(app.cnAxes);
            imagesc(app.pnr,'parent',app.pnrAxes);hold(app.pnrAxes, 'on');colorbar(app.pnrAxes);
            imagesc(app.cn.*app.pnr,'parent',app.pnrcnAxes);hold(app.pnrcnAxes, 'on');colorbar(app.pnrcnAxes);
            
            
            cn_ind = app.ind & (app.cn>=app.corrSpinner.Value & app.mask) ;
            [r1, c1] = find(cn_ind);
            plot(c1, r1, '.r', 'markersize', 10,'parent',app.cnAxes);
            
            
            pnr_ind = app.ind & (app.pnr>=app.PNRSpinner.Value & app.mask);
            [r2, c2] = find(pnr_ind);
            plot(c2, r2, '.r', 'markersize', 10,'parent',app.pnrAxes);
            
            app.tmp_ind = cn_ind & pnr_ind;
            [r, c] = find(app.tmp_ind);
            plot(c, r, '.m', 'markersize', 10,'parent',app.pnrcnAxes);
        end

        % Value changed function: gSigSpinner
        function gSigSpinnerValueChanged(app, event)
            app.gSig = app.gSigSpinner.Value;
            tmp_d = max(1,round(app.gSig));
            v_max = ordfilt2(app.cn.*app.pnr, tmp_d^2, true(tmp_d));      
            app.ind = (v_max==app.cn.*app.pnr);
            
            cla(app.cnAxes);cla(app.pnrAxes);cla(app.pnrcnAxes);
            
            imagesc(app.cn,'parent',app.cnAxes);hold(app.cnAxes, 'on');colorbar(app.cnAxes);
            imagesc(app.pnr,'parent',app.pnrAxes);hold(app.pnrAxes, 'on');colorbar(app.pnrAxes);
            imagesc(app.cn.*app.pnr,'parent',app.pnrcnAxes);hold(app.pnrcnAxes, 'on');colorbar(app.pnrcnAxes);
            
            
            cn_ind = app.ind & (app.cn>=app.corrSpinner.Value & app.mask) ;
            [r1, c1] = find(cn_ind);
            plot(c1, r1, '.r', 'markersize', 10,'parent',app.cnAxes);
            
            
            pnr_ind = app.ind & (app.pnr>=app.PNRSpinner.Value & app.mask);
            [r2, c2] = find(pnr_ind);
            plot(c2, r2, '.r', 'markersize', 10,'parent',app.pnrAxes);
            
            app.tmp_ind = cn_ind & pnr_ind;
            [r, c] = find(app.tmp_ind);
            plot(c, r, '.m', 'markersize', 10,'parent',app.pnrcnAxes);
            
        end

        % Value changing function: pnr_slider
        function pnr_sliderValueChanging(app, event)
            changingValue = event.Value;
            clim(app.pnrAxes,[0 changingValue]);
            clim(app.pnrcnAxes,[0 changingValue*app.corr_slider.Value]);
            
            
        end

        % Value changing function: corr_slider
        function corr_sliderValueChanging(app, event)
            changingValue = event.Value;
            clim(app.cnAxes,[0 changingValue]);
            clim(app.pnrcnAxes,[0 changingValue*app.pnr_slider.Value]);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pnrAxes = uiaxes(app.UIFigure);
            title(app.pnrAxes, 'Peak-to-noise ratio')
            zlabel(app.pnrAxes, 'Z')
            app.pnrAxes.XTick = [];
            app.pnrAxes.YTick = [];
            app.cnAxes = uiaxes(app.UIFigure);
            title(app.cnAxes, 'Correlation')
            zlabel(app.cnAxes, 'Z')
            app.cnAxes.XTick = [];
            app.cnAxes.YTick = [];
            app.pnrcnAxes = uiaxes(app.UIFigure);
            title(app.pnrcnAxes, 'PNR·Correlation')
            zlabel(app.pnrcnAxes, 'Z')
            app.pnrcnAxes.XTick = [];
            app.pnrcnAxes.YTick = [];
            app.PNRSpinnerLabel = uilabel(app.UIFigure);
            app.PNRSpinnerLabel.Text = 'PNR';
            app.PNRSpinner = uispinner(app.UIFigure);
            app.PNRSpinner.Step = 0.5;
            app.PNRSpinner.ValueChangedFcn = createCallbackFcn(app, @PNRSpinnerValueChanged, true);
            app.corrSpinnerLabel = uilabel(app.UIFigure);
            app.corrSpinnerLabel.Text = 'corr.';
            app.corrSpinner = uispinner(app.UIFigure);
            app.corrSpinner.Step = 0.05;
            app.corrSpinner.ValueChangedFcn = createCallbackFcn(app, @corrSpinnerValueChanged, true);
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok!';
            app.DrawMaskButton = uibutton(app.UIFigure, 'push');
            app.DrawMaskButton.ButtonPushedFcn = createCallbackFcn(app, @DrawMaskButtonPushed, true);
            app.DrawMaskButton.Text = 'Draw Mask';
            app.ApplyMaskButton = uibutton(app.UIFigure, 'push');
            app.ApplyMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyMaskButtonPushed, true);
            app.ApplyMaskButton.Enable = 'off';
            app.ApplyMaskButton.Text = 'Apply Mask';
            app.DrawmaskinthecorrelationprojectionLabel = uilabel(app.UIFigure);
            app.DrawmaskinthecorrelationprojectionLabel.Text = 'Draw mask in the correlation projection';
            app.gSigSpinnerLabel = uilabel(app.UIFigure);
            app.gSigSpinnerLabel.Text = 'gSig';
            app.gSigSpinner = uispinner(app.UIFigure);
            app.gSigSpinner.Step = 0.5;
            app.gSigSpinner.Limits = [0.5 15];
            app.gSigSpinner.ValueChangedFcn = createCallbackFcn(app, @gSigSpinnerValueChanged, true);
            app.gSigSpinner.Value = 2.5;
            app.NeuronsizeLabel = uilabel(app.UIFigure);
            app.NeuronsizeLabel.Text = 'Neuron size';
            app.pnr_slider = uislider(app.UIFigure);
            app.pnr_slider.ValueChangingFcn = createCallbackFcn(app, @pnr_sliderValueChanging, true);
            app.corr_slider = uislider(app.UIFigure);
            app.corr_slider.ValueChangingFcn = createCallbackFcn(app, @corr_sliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = estimate_Corr_PNR(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end