classdef estimate_Corr_PNR_dendrite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RefinesegmentsButton            matlab.ui.control.StateButton
        ApplyMaskButton                 matlab.ui.control.Button
        DrawMaskButton                  matlab.ui.control.Button
        OkButton                        matlab.ui.control.Button
        IntensityThresholdSpinner       matlab.ui.control.Spinner
        IntensityThresholdSpinnerLabel  matlab.ui.control.Label
        MindendritelengthSpinner        matlab.ui.control.Spinner
        MindendritelengthSpinnerLabel   matlab.ui.control.Label
        skelAxes                        matlab.ui.control.UIAxes
        filteredAxes                    matlab.ui.control.UIAxes
        pnrAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        ind % Description
        tmp_ind
        min_corr
        min_pnr
        pnr_vmax
        pnr
        min_dendrite_size
        cn
        mask
        done
        ROI
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, cn, pnr, min_dendrite_size, dendrite_initialization_threshold, mask)
            % Initialize variable
            app.done=0;
            app.cn=cn;
            app.mask=logical(mask);
            app.pnr=pnr;
            app.IntensityThresholdSpinner.Value=dendrite_initialization_threshold;
            app.MindendritelengthSpinner.Value=min_dendrite_size;
            app.pnr_vmax=prctile(pnr,99.9,"all");

            [~,contour] = segmentAndColorVessels(app.cn,app.MindendritelengthSpinner.Value,app.IntensityThresholdSpinner.Value,app.mask);

            axis(app.filteredAxes, 'tight')
            axis(app.pnrAxes, 'tight')
            axis(app.skelAxes, 'tight')
            imagesc(app.cn,'parent',app.filteredAxes);hold(app.filteredAxes, 'on');colorbar(app.filteredAxes);clim(app.filteredAxes,[0 1]);colormap(app.filteredAxes,'parula');
            imagesc(app.pnr,'parent',app.pnrAxes);hold(app.pnrAxes, 'on');colorbar(app.pnrAxes);clim(app.pnrAxes,[0 app.pnr_vmax]);colormap(app.pnrAxes,'parula');
            imshow(contour,'parent',app.skelAxes);hold(app.skelAxes, 'on');colorbar(app.skelAxes);
        end

        % Value changed function: MindendritelengthSpinner
        function MindendritelengthSpinnerValueChanged(app, event)
            cla(app.filteredAxes);cla(app.pnrAxes);cla(app.skelAxes);

            [~,contour] = segmentAndColorVessels(app.cn,app.MindendritelengthSpinner.Value,app.IntensityThresholdSpinner.Value,app.mask);

            imagesc(app.cn,'parent',app.filteredAxes);hold(app.filteredAxes, 'on');colorbar(app.filteredAxes);clim(app.filteredAxes,[0 1]);colormap(app.filteredAxes,'parula');
            imagesc(app.pnr,'parent',app.pnrAxes);hold(app.pnrAxes, 'on');colorbar(app.pnrAxes);clim(app.pnrAxes,[0 app.pnr_vmax]);colormap(app.pnrAxes,'parula');
            imshow(contour,'parent',app.skelAxes);hold(app.skelAxes, 'on');colorbar(app.skelAxes);


        end

        % Value changed function: IntensityThresholdSpinner
        function IntensityThresholdSpinnerValueChanged(app, event)
            cla(app.filteredAxes);cla(app.pnrAxes);cla(app.skelAxes);

            [~,contour] = segmentAndColorVessels(app.cn,app.MindendritelengthSpinner.Value,app.IntensityThresholdSpinner.Value,app.mask);

            imagesc(app.cn,'parent',app.filteredAxes);hold(app.filteredAxes, 'on');colorbar(app.filteredAxes);clim(app.filteredAxes,[0 1]);colormap(app.filteredAxes,'parula');
            imagesc(app.pnr,'parent',app.pnrAxes);hold(app.pnrAxes, 'on');colorbar(app.pnrAxes);clim(app.pnrAxes,[0 app.pnr_vmax]);colormap(app.pnrAxes,'parula');
            imshow(contour,'parent',app.skelAxes);hold(app.skelAxes, 'on');colorbar(app.skelAxes);

        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.done=1;

        end

        % Button pushed function: DrawMaskButton
        function DrawMaskButtonPushed(app, event)
            temp = drawpolygon(app.filteredAxes);
            app.ROI = createMask(temp);
            if (sum(app.ROI,'all')>0)
                app.ApplyMaskButton.Enable = 'on';
            end
        end

        % Button pushed function: ApplyMaskButton
        function ApplyMaskButtonPushed(app, event)
            app.mask=app.ROI;

            cla(app.filteredAxes);cla(app.pnrAxes);cla(app.skelAxes);

            [~,contour] = segmentAndColorVessels(app.cn,app.MindendritelengthSpinner.Value,app.IntensityThresholdSpinner.Value,app.mask);

            imagesc(app.cn,'parent',app.filteredAxes);hold(app.filteredAxes, 'on');colorbar(app.filteredAxes);clim(app.filteredAxes,[0 1]);colormap(app.filteredAxes,'parula');
            imagesc(app.pnr,'parent',app.pnrAxes);hold(app.pnrAxes, 'on');colorbar(app.pnrAxes);clim(app.pnrAxes,[0 app.pnr_vmax]);colormap(app.pnrAxes,'parula');
            imshow(contour,'parent',app.skelAxes);hold(app.skelAxes, 'on');colorbar(app.skelAxes);

        end

        % Value changed function: RefinesegmentsButton
        function RefinesegmentsButtonValueChanged(app, event)
            value = app.RefinesegmentsButton.Value;
            app.UIFigure.Pointer = 'watch';
            drawnow; % Force immediate update
            [~,contour] = get_refined_dendrite_segmentations(app.cn,app.MindendritelengthSpinner.Value,app.IntensityThresholdSpinner.Value,app.mask);
            imagesc(app.cn,'parent',app.filteredAxes);hold(app.filteredAxes, 'on');colorbar(app.filteredAxes);clim(app.filteredAxes,[0 1]);colormap(app.filteredAxes,'parula');
            imagesc(app.pnr,'parent',app.pnrAxes);hold(app.pnrAxes, 'on');colorbar(app.pnrAxes);clim(app.pnrAxes,[0 app.pnr_vmax]);colormap(app.pnrAxes,'parula');
            imshow(contour,'parent',app.skelAxes);hold(app.skelAxes, 'on');colorbar(app.skelAxes);
            app.UIFigure.Pointer = 'arrow';
            drawnow; % Force immediate update
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pnrAxes = uiaxes(app.UIFigure);
            title(app.pnrAxes, 'Peak-to-noise ratio (output seeds)')
            zlabel(app.pnrAxes, 'Z')
            app.pnrAxes.XTick = [];
            app.pnrAxes.YTick = [];
            app.filteredAxes = uiaxes(app.UIFigure);
            title(app.filteredAxes, 'Filtered projection ')
            zlabel(app.filteredAxes, 'Z')
            app.filteredAxes.XTick = [];
            app.filteredAxes.YTick = [];
            app.skelAxes = uiaxes(app.UIFigure);
            title(app.skelAxes, 'Segemented dendrites')
            zlabel(app.skelAxes, 'Z')
            app.skelAxes.XTick = [];
            app.skelAxes.YTick = [];
            app.MindendritelengthSpinnerLabel = uilabel(app.UIFigure);
            app.MindendritelengthSpinnerLabel.Text = 'Min. dendrite length';
            app.MindendritelengthSpinner = uispinner(app.UIFigure);
            app.MindendritelengthSpinner.Step = 5;
            app.MindendritelengthSpinner.ValueChangedFcn = createCallbackFcn(app, @MindendritelengthSpinnerValueChanged, true);
            app.IntensityThresholdSpinnerLabel = uilabel(app.UIFigure);
            app.IntensityThresholdSpinnerLabel.Text = 'Intensity Threshold';
            app.IntensityThresholdSpinner = uispinner(app.UIFigure);
            app.IntensityThresholdSpinner.Step = 0.05;
            app.IntensityThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @IntensityThresholdSpinnerValueChanged, true);
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok!';
            app.DrawMaskButton = uibutton(app.UIFigure, 'push');
            app.DrawMaskButton.ButtonPushedFcn = createCallbackFcn(app, @DrawMaskButtonPushed, true);
            app.DrawMaskButton.Text = 'Draw Mask';
            app.ApplyMaskButton = uibutton(app.UIFigure, 'push');
            app.ApplyMaskButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyMaskButtonPushed, true);
            app.ApplyMaskButton.Enable = 'off';
            app.ApplyMaskButton.Text = 'Apply Mask';
            app.RefinesegmentsButton = uibutton(app.UIFigure, 'state');
            app.RefinesegmentsButton.ValueChangedFcn = createCallbackFcn(app, @RefinesegmentsButtonValueChanged, true);
            app.RefinesegmentsButton.Text = 'Refine segments';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = estimate_Corr_PNR_dendrite(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end