classdef NeuronSize_app_in < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Slider      matlab.ui.control.Slider
        DoneButton  matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        N
        done
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, N, lim)
            app.N=N;
            app.Slider.Limits=[min(lim),max(lim)];
            app.Slider.MajorTicks=lim;

            app.Slider.Value=lim(1);
  
            X=mat2gray(app.N(:,:,1));

            imshow(X,'Parent',app.UIAxes);

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.done=1;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            val = app.Slider.Value;
            ticks = app.Slider.MajorTicks;

            % Find the closest tick
            [~, idx] = min(abs(ticks - val));
            snappedVal = ticks(idx);

            % Set slider value to snapped value
            app.Slider.Value = snappedVal;

            I=find(snappedVal==ticks);
            X=mat2gray(app.N(:,:,I));

            imshow(X,'Parent',app.UIAxes);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeuronSize_app_in(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end