classdef Ca_video_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button              matlab.ui.control.StateButton
        SliderContrastUp    matlab.ui.control.Slider
        SliderContrastDown  matlab.ui.control.Slider
        SliderFrame         matlab.ui.control.Slider
        EmiHist             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        V % Description
        s1
        s2
        changingValue
        play
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, in)
            app.V=in;
            temp= app.V(:,:,1);
            % create contrast variable
            app.s1=double(round(prctile(temp(:),5)));
            app.s2=double(round(prctile(temp(:),98)));
            % initialize video
            imshow(temp,[app.s1 app.s2],'parent',app.UIAxes);
            m=double(max(app.V,[],'all'));
            histogram(temp(:),'BinLimits',[0 m],'parent',app.EmiHist);          
            xlim(app.EmiHist,[0, m]);
            % initialize Slider frames
            app.SliderFrame.Limits=[1 size( app.V,3)];
            app.changingValue=1;
            % initialize Slider contrast up
            
            app.SliderContrastUp.Limits=[2 m];
            app.SliderContrastUp.Value=app.s2;
            app.SliderContrastDown.Limits=[1 m-1];
            app.SliderContrastDown.Value=1;
        end

        % Value changing function: SliderFrame
        function SliderFrameValueChanging(app, event)
            app.play=0;        
            app.Button.Value=0;
            if (abs(app.changingValue-event.Value)>3)
                app.changingValue = event.Value;
                imshow(app.V(:,:,round(app.changingValue)),[app.s1 app.s2],'parent',app.UIAxes);
                histogram(reshape(app.V(:,:,round(app.changingValue)),1,[]),'BinLimits',[0 max(app.V,[],'all')],...
                    'Normalization','pdf','parent',app.EmiHist);
                drawnow
            end
            
        end

        % Value changing function: SliderContrastDown
        function SliderContrastUpValueChanging(app, event)
            app.s1 = round(event.Value);
            if app.s1>=app.s2
                app.s2=app.s1+1;
                app.SliderContrastUp.Value=app.s2;
            end
            imshow(app.V(:,:,round(app.changingValue)),[app.s1 app.s2],'parent',app.UIAxes);
            histogram(reshape(app.V(:,:,round(app.changingValue)),1,[]),'BinLimits',[0 max(app.V,[],'all')],'Normalization','pdf','parent',app.EmiHist);
        end

        % Value changing function: SliderContrastUp
        function SliderContrastUpValueChanging2(app, event)
            app.s2 = round(event.Value);
            if app.s1>=app.s2
                app.s1=app.s2-1;
                app.SliderContrastDown.Value=app.s1;
            end
            
            imshow(app.V(:,:,round(app.changingValue)),[app.s1 app.s2],'parent',app.UIAxes);
            histogram(reshape(app.V(:,:,round(app.changingValue)),1,[]),'BinLimits',[0 max(app.V,[],'all')],'Normalization','pdf','parent',app.EmiHist);
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            app.play = app.Button.Value;
            app.Button.BackgroundColor=[1,0,0];
            if app.play==1
                while app.play==1
                    if round(app.changingValue)>=size( app.V,3)  %stop if video ends
                        app.play=0;
                        app.Button.Value=0;
                        app.Button.BackgroundColor=[0.06,0.59,0.06];
                        break
                    end
                    if app.Button.Value ==0 %stop button
                        app.play=0;
                        app.Button.Value=0;
                                     app.Button.BackgroundColor=[0.06,0.59,0.06];   
                        break
                    end
                    app.changingValue=app.changingValue+1;
                    imshow(app.V(:,:,round(app.changingValue)),[app.s1 app.s2],'parent',app.UIAxes);
                    histogram(reshape(app.V(:,:,round(app.changingValue)),1,[]),'BinLimits',[0 max(app.V,[],'all')],'Normalization','pdf','parent',app.EmiHist);
                    app.SliderFrame.Value=app.changingValue;
                    app.play=app.Button.Value;
                    pause(0.0001)
                end            
   
            else
             app.Button.BackgroundColor=[0.06,0.59,0.06];   
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.EmiHist = uiaxes(app.UIFigure);
            zlabel(app.EmiHist, 'Z')
            app.EmiHist.XTickLabel = {'0'; '0.5'; '1'};
            app.EmiHist.YTick = [];
            app.SliderFrame = uislider(app.UIFigure);
            app.SliderFrame.ValueChangingFcn = createCallbackFcn(app, @SliderFrameValueChanging, true);
            app.SliderContrastDown = uislider(app.UIFigure);
            app.SliderContrastDown.MajorTicks = [];
            app.SliderContrastDown.MajorTickLabels = {''};
            app.SliderContrastDown.ValueChangingFcn = createCallbackFcn(app, @SliderContrastUpValueChanging, true);
            app.SliderContrastDown.MinorTicks = [];
            app.SliderContrastUp = uislider(app.UIFigure);
            app.SliderContrastUp.MajorTicks = [];
            app.SliderContrastUp.ValueChangingFcn = createCallbackFcn(app, @SliderContrastUpValueChanging2, true);
            app.SliderContrastUp.MinorTicks = [];
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ca_video_viewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end