classdef Crop_app_single < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        CropButton  matlab.ui.control.Button
        DrawButton  matlab.ui.control.Button
        ClickdrawdragasquareintheFOVclickcropLabel  matlab.ui.control.Label
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Y % Description
        ROI
        ver
        done
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Y)
            app.Y=Y;   
            imshow(app.Y(:,:,1),'parent',app.UIAxes)
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.UIFigure.Pointer = 'crosshair';   % options: 'crosshair', 'hand', 'fleur', etc.

            % Now start drawing
            app.ROI = []; 
            app.ROI = drawrectangle(app.UIAxes);
            app.CropButton.Enable = true;
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
              app.ver=round(app.ROI.Vertices);
              app.done=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.ClickdrawdragasquareintheFOVclickcropLabel = uilabel(app.UIFigure);
            app.ClickdrawdragasquareintheFOVclickcropLabel.Text = 'Click draw -> drag a square in the FOV -> click crop';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Enable = 'off';
            app.CropButton.Text = 'Crop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crop_app_single(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end