classdef crop_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        CropButton  matlab.ui.control.Button
        DrawButton  matlab.ui.control.Button
        Slider      matlab.ui.control.Slider
        ClickdrawdragasquareintheFOVclickcropLabel  matlab.ui.control.Label
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Y % Description
        ROI
        ver
        done
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Y)
            app.Y=Y;   
            app.Slider.Value=1;
            app.Slider.Limits=[1,size(Y,3)]; 
            app.Slider.MajorTicks = 1:size(Y,3);   
            app.Slider.MinorTicks = []; % show integer tick marks
            imshow(app.Y(:,:,1),'parent',app.UIAxes)
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.UIFigure.Pointer = 'crosshair';   % options: 'crosshair', 'hand', 'fleur', etc.

            % Now start drawing
            app.ROI = []; 
            app.ROI = drawrectangle(app.UIAxes);
            app.CropButton.Enable = true;
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
              app.ver=round(app.ROI.Vertices);
              app.done=1;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.Slider.Value = changingValue; 
            imshow(app.Y(:,:,changingValue),'parent',app.UIAxes)
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.Slider.Value = round(value); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.ClickdrawdragasquareintheFOVclickcropLabel = uilabel(app.UIFigure);
            app.ClickdrawdragasquareintheFOVclickcropLabel.Text = 'Click draw -> drag a square in the FOV -> click crop';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 5];
            app.Slider.MajorTicks = [1 2 3 4 5];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Enable = 'off';
            app.CropButton.Text = 'Crop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crop_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end