classdef BV_app_in < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        DoneButton  matlab.ui.control.Button
        Slider      matlab.ui.control.RangeSlider
        UIAxes      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        BV
        done
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, BV)
            app.BV=BV;
            lim=app.Slider.Limits;

            ran=range(lim);
            res=size(app.BV,3);

            r=app.Slider.Value;

            r1=(r(1)-lim(1))./ran;
            r2=(r(2)-lim(1))./ran;

            ix1=round((res-1)*r1)+1;
            ix2=round((res-1)*r2)+1;

            X=mat2gray(sum(app.BV(:,:,ix1:ix2),3));

            imshow(X,'Parent',app.UIAxes);

        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            % changingValue = event.Value;


        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            lim=app.Slider.Limits;

            ran=range(lim);
            res=size(app.BV,3);

            r=app.Slider.Value;

            r1=(r(1)-lim(1))./ran;
            r2=(r(2)-lim(1))./ran;

            ix1=round((res-1)*r1)+1;
            ix2=round((res-1)*r2)+1;

            X=mat2gray(sum(app.BV(:,:,ix1:ix2),3));

            imshow(X,'Parent',app.UIAxes);

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.done=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.Slider = uislider(app.UIFigure, 'range');
            app.Slider.Limits = [0.1 6];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Step = 0.05;
            app.Slider.Value = [1.5 2.25];
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BV_app_in(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end