classdef View_components < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ProjectionDropDown       matlab.ui.control.DropDown
        ProjectionDropDownLabel  matlab.ui.control.Label
        DoneButton               matlab.ui.control.Button
        LeftclickSelectcomponentRightclickdeleteincludecomponentLabel  matlab.ui.control.Label
        ClearselectionButton     matlab.ui.control.Button
        ResetzoomButton          matlab.ui.control.Button
        Slider                   matlab.ui.control.Slider
        SeparateSpatialButton    matlab.ui.control.Button
        ColormapDropDown         matlab.ui.control.DropDown
        ColormapDropDownLabel    matlab.ui.control.Label
        Tr1                      matlab.ui.control.UIAxes
        mainAx                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        cur
        neuron
        c
        win
        p
        I
        done
        A
    end

    methods (Access = private)

        function show_cont(app,neuron)
            app.neuron=neuron;
            cmap = spring(3*45);

            Cn=neuron.CaliAli_options.inter_session_alignment.Cn;
            % PNR=neuron.PNR;
            % Cn=v2uint8(mat2gray(PNR).*mat2gray(Cn).^2);
            % for i=1:size(Cn,3)
            %     Cn(:,:,i)=adapthisteq(Cn(:,:,i));
            % end
            app.I=imagesc(Cn,'Parent',app.mainAx);
            % Remove tick labels
            app.mainAx.XAxis.TickLabels = {};
            app.mainAx.YAxis.TickLabels = {};
            % Set limits of axes
            app.mainAx.XLim = [0 app.I.XData(2)];
            app.mainAx.YLim = [0 app.I.YData(2)];
            app.cur=[];
            hold(app.mainAx, 'on')

            for i=1:size(neuron.Coor,1)
                cc=neuron.Coor{i, 1};
                plot(cc(1,:),cc(2,:),'Color',cmap(randi(50),:),'LineWidth',1,'ButtonDownFcn',{@mouse_click,app},...
                    'Tag',num2str(i),'Parent',app.mainAx);
            end


            hold(app.mainAx, 'off')
            app.c=repelem([1 0.3134 0.6866],size(neuron.Coor,1),1);
        end
    end


    methods (Access = public)

        function unplot(app,t)
            h1 = findobj(app.mainAx.Children,'Tag',num2str(t));
            mouse_click(h1,[],app)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, neuron, thr)
            app.done=0;
            if ~exist('thr','var')
                thr=0.6;
            end
            app.neuron=neuron;
            app.win=1000;
            neuron.Coor=neuron.get_contours(thr);
            A=full(neuron.A);
            A=A./max(A);
            app.A=reshape(max(A,[],2),neuron.options.d1,neuron.options.d2,[]);
            disableDefaultInteractivity(app.Tr1)
            show_cont(app,neuron)

            disableDefaultInteractivity(app.mainAx)
            app.mainAx.Interactions = regionZoomInteraction;
            enableDefaultInteractivity(app.mainAx)

        end

        % Callback function
        function XButton_Pushed(app, event)
            t=app.cur(str2double(event.Source.Tag(2)));
            if t~=0
                app.c(t,:)=[0 0 0];
                unplot(app,t)
            end
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            colormap(app.mainAx,value)
        end

        % Button pushed function: SeparateSpatialButton
        function SeparateSpatialButtonPushed(app, event)
            ix_in=sum(app.c,2)~=3;
            ix=manually_classify_spatial_fun(app.neuron,ix_in);
            kill=find(ix==0);
            app.c(~ix,:)=repmat([1 1 1],length(kill),1);
            for i=1:length(kill)
                line=findobj(app.mainAx.Children,'tag',num2str(kill(i)));
                line.Color=app.c(kill(i),:);
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            ran=length(app.Tr1.Children(1).YData)-app.win;
            xlim(app.Tr1,[changingValue/100*ran,changingValue*ran/100+app.win])
            ylim(app.Tr1,[-20*length(app.Tr1.Children),40]);
        end

        % Button pushed function: ResetzoomButton
        function ResetzoomButtonPushed(app, event)
            app.mainAx.XAxis.TickLabels = {};
            app.mainAx.YAxis.TickLabels = {};
            % Set limits of axes
            app.mainAx.XLim = [0 app.I.XData(2)];
            app.mainAx.YLim = [0 app.I.YData(2)];
        end

        % Button pushed function: ClearselectionButton
        function ClearselectionButtonPushed(app, event)
            for i=1:length(app.cur)
                t=app.cur(i);
                delete(findobj(app.Tr1,'tag',num2str(t)));
                set(findobj(app.mainAx,'tag',num2str(t)),'Color',app.c(t,:))
            end
            app.cur=[];
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.done=1;
        end

        % Value changed function: ProjectionDropDown
        function ProjectionDropDownValueChanged(app, event)
            value = app.ProjectionDropDown.Value;
            if strcmp(value,'PNR')
                set(app.I, 'CData', app.neuron.CaliAli_options.inter_session_alignment.PNR);
            elseif strcmp(value,'Correlation')
                set(app.I, 'CData', app.neuron.CaliAli_options.inter_session_alignment.Cn);
            elseif strcmp(value,'A')
                set(app.I, 'CData', app.A);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.mainAx = uiaxes(app.UIFigure);
            zlabel(app.mainAx, 'Z')
            app.mainAx.XTick = [];
            app.mainAx.YTick = [];
            app.Tr1 = uiaxes(app.UIFigure);
            zlabel(app.Tr1, 'Z')
            app.Tr1.XTick = [];
            app.Tr1.YTick = [];
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'Parula', 'Gray', 'Hot', 'Jet'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Parula';
            app.SeparateSpatialButton = uibutton(app.UIFigure, 'push');
            app.SeparateSpatialButton.ButtonPushedFcn = createCallbackFcn(app, @SeparateSpatialButtonPushed, true);
            app.SeparateSpatialButton.Text = 'Separate Spatial';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Visible = 'off';
            app.ResetzoomButton = uibutton(app.UIFigure, 'push');
            app.ResetzoomButton.ButtonPushedFcn = createCallbackFcn(app, @ResetzoomButtonPushed, true);
            app.ResetzoomButton.Text = 'Reset zoom';
            app.ClearselectionButton = uibutton(app.UIFigure, 'push');
            app.ClearselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearselectionButtonPushed, true);
            app.ClearselectionButton.Text = 'Clear selection';
            app.LeftclickSelectcomponentRightclickdeleteincludecomponentLabel = uilabel(app.UIFigure);
            app.LeftclickSelectcomponentRightclickdeleteincludecomponentLabel.Text = {'Left click: Select component.'; 'Right click: delete/include component.'};
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done!';
            app.ProjectionDropDownLabel = uilabel(app.UIFigure);
            app.ProjectionDropDownLabel.Text = 'Projection';
            app.ProjectionDropDown = uidropdown(app.UIFigure);
            app.ProjectionDropDown.Items = {'Correlation', 'PNR', 'A'};
            app.ProjectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectionDropDownValueChanged, true);
            app.ProjectionDropDown.Value = 'Correlation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = View_components(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end