classdef manual_residuals_PNR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ClearseedsButton  matlab.ui.control.Button
        OkButton          matlab.ui.control.Button
        pnrcnAxes_2       matlab.ui.control.UIAxes
        cnAxes_2          matlab.ui.control.UIAxes
        pnrAxes_2         matlab.ui.control.UIAxes
        pnrcnAxes         matlab.ui.control.UIAxes
        cnAxes            matlab.ui.control.UIAxes
        pnrAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ind % Description
        pnr_vmax
        PNR
        Cn
        PNRr
        Cnr
        done
        seed
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Cn, PNR, Cnr, PNRr, Coor)
            % Initialize variable
            app.done=0;
            app.Cn=Cn;
            app.seed=Cn*0;
            app.PNR=PNR;
            app.Cnr=Cnr;
            app.PNRr=PNRr;


            app.pnr_vmax =  median(PNR,'all','omitnan')+3*(median(PNR,'all','omitnan')/0.6745);
            axis(app.cnAxes, 'tight')
            axis(app.pnrAxes, 'tight')
            axis(app.pnrcnAxes, 'tight')         
            cmap = spring(3*45);

            imagesc(app.Cn,'parent',app.cnAxes,'ButtonDownFcn',{@image_click_res,app});hold(app.cnAxes, 'on');colorbar(app.cnAxes);caxis(app.cnAxes,[0 1]);colormap(app.cnAxes,'parula');
            imagesc(app.PNR,'parent',app.pnrAxes,'ButtonDownFcn',{@image_click_res,app});hold(app.pnrAxes, 'on');colorbar(app.pnrAxes);caxis(app.pnrAxes,[0 app.pnr_vmax]);colormap(app.pnrAxes,'parula');
            imagesc(app.Cn.*app.PNR,'parent',app.pnrcnAxes,'ButtonDownFcn',{@image_click_res,app});hold(app.pnrcnAxes, 'on');colorbar(app.pnrcnAxes);caxis(app.pnrcnAxes,[0 app.pnr_vmax*0.8]);colormap(app.pnrcnAxes,'parula');;

            axis(app.cnAxes_2, 'tight')
            axis(app.pnrAxes_2, 'tight')
            axis(app.pnrcnAxes_2, 'tight')
            imagesc(app.Cnr,'parent',app.cnAxes_2,'ButtonDownFcn',{@image_click_res,app});hold(app.cnAxes_2, 'on');colorbar(app.cnAxes_2);caxis(app.cnAxes_2,[0 1]);colormap(app.cnAxes_2,'parula');
            imagesc(app.PNRr,'parent',app.pnrAxes_2,'ButtonDownFcn',{@image_click_res,app});hold(app.pnrAxes_2, 'on');colorbar(app.pnrAxes_2);caxis(app.pnrAxes_2,[0 app.pnr_vmax]);colormap(app.pnrAxes_2,'parula');
            imagesc(app.Cnr.*app.PNRr,'parent',app.pnrcnAxes_2,'ButtonDownFcn',{@image_click_res,app});hold(app.pnrcnAxes_2, 'on');colorbar(app.pnrcnAxes_2);caxis(app.pnrcnAxes_2,[0 app.pnr_vmax*0.8]);colormap(app.pnrcnAxes_2,'parula');

            for i=1:size(Coor,1)
                cc=Coor{i, 1};
                plot(cc(1,:),cc(2,:),'Color',cmap(randi(50),:),'LineWidth',1,'Parent',app.cnAxes);
                plot(cc(1,:),cc(2,:),'Color',cmap(randi(50),:),'LineWidth',1,'Parent',app.pnrAxes);
                plot(cc(1,:),cc(2,:),'Color',cmap(randi(50),:),'LineWidth',1,'Parent',app.pnrcnAxes);
                plot(cc(1,:),cc(2,:),'Color',cmap(randi(50),:),'LineWidth',1,'Parent',app.cnAxes_2);
                plot(cc(1,:),cc(2,:),'Color',cmap(randi(50),:),'LineWidth',1,'Parent',app.pnrAxes_2);
                plot(cc(1,:),cc(2,:),'Color',cmap(randi(50),:),'LineWidth',1,'Parent',app.pnrcnAxes_2);
            end


        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.done=1;
            
        end

        % Button pushed function: ClearseedsButton
        function ClearseedsButtonPushed(app, event)
            app.seed=app.seed*0;
            h=findobj(app.cnAxes,'Type','scatter');
            delete(h);
            h=findobj(app.pnrAxes,'Type','scatter');
            delete(h);
            h=findobj(app.pnrcnAxes,'Type','scatter');
            delete(h);
            h=findobj(app.cnAxes_2,'Type','scatter');
            delete(h);
            h=findobj(app.pnrAxes_2,'Type','scatter');
            delete(h);
            h=findobj(app.pnrcnAxes_2,'Type','scatter');
            delete(h);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pnrAxes = uiaxes(app.UIFigure);
            title(app.pnrAxes, 'Peak-to-noise ratio')
            zlabel(app.pnrAxes, 'Z')
            app.pnrAxes.XTick = [];
            app.pnrAxes.YTick = [];
            app.cnAxes = uiaxes(app.UIFigure);
            title(app.cnAxes, 'Correlation')
            zlabel(app.cnAxes, 'Z')
            app.cnAxes.XTick = [];
            app.cnAxes.YTick = [];
            app.pnrcnAxes = uiaxes(app.UIFigure);
            title(app.pnrcnAxes, 'PNR·Correlation')
            zlabel(app.pnrcnAxes, 'Z')
            app.pnrcnAxes.XTick = [];
            app.pnrcnAxes.YTick = [];
            app.pnrAxes_2 = uiaxes(app.UIFigure);
            title(app.pnrAxes_2, 'Peak-to-noise ratio')
            zlabel(app.pnrAxes_2, 'Z')
            app.pnrAxes_2.XTick = [];
            app.pnrAxes_2.YTick = [];
            app.cnAxes_2 = uiaxes(app.UIFigure);
            title(app.cnAxes_2, 'Correlation')
            zlabel(app.cnAxes_2, 'Z')
            app.cnAxes_2.XTick = [];
            app.cnAxes_2.YTick = [];
            app.pnrcnAxes_2 = uiaxes(app.UIFigure);
            title(app.pnrcnAxes_2, 'PNR·Correlation')
            zlabel(app.pnrcnAxes_2, 'Z')
            app.pnrcnAxes_2.XTick = [];
            app.pnrcnAxes_2.YTick = [];
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok!';
            app.ClearseedsButton = uibutton(app.UIFigure, 'push');
            app.ClearseedsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearseedsButtonPushed, true);
            app.ClearseedsButton.Text = 'Clear seeds';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manual_residuals_PNR(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end