classdef manually_classify_spatial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Slider2     matlab.ui.control.Slider
        DoneButton  matlab.ui.control.Button
        Slider      matlab.ui.control.Slider
        ButtonL     matlab.ui.control.Button
        ButtonR     matlab.ui.control.Button
        UIAxes2     matlab.ui.control.UIAxes
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ix  % index
        m   % temp image 1
        m2   % temp image 1
        id  % neuron id
        A    % spatial components
        ix_c  % correct index
        ix_inc % incorrect index
        done
    end
    
    methods (Access = public)
        
        function plot_components(app)
            [app.A,I]=sort_component(app.A,app.ix);

            app.ix=app.ix(I);
            app.id=app.id(I);
            %%
            if sum(app.ix)>0
                app.ix_c=app.ix(app.ix);
                A_keep = imtile(app.A(:,:,:,app.ix),'GridSize',[nan 10]);
                app.m=imagesc(A_keep,'Parent',app.UIAxes,'ButtonDownFcn',{@image_click,app});caxis(app.UIAxes,[0 0.2]);colormap(app.UIAxes,'hot');
                app.UIAxes.XLim = [0 app.m.XData(2)];
                disableDefaultInteractivity(app.UIAxes)
                if sum(app.ix)>100
                    app.Slider.Visible=1;
                    changingValue=app.Slider.Value;
                    ran=app.m.YData(1,2)-500;
                    ylim(app.UIAxes,[(100-changingValue)/100*ran,(100-changingValue)*ran/100+500]);
                else
                    app.Slider.Visible=0;
                    ylim(app.UIAxes,[0 500])
                end
            end
            %%
            if sum(~app.ix)>0
                app.ix_inc=app.ix(~app.ix);
                A_discard= imtile(app.A(:,:,:,~app.ix),'GridSize',[nan 10]);
                app.m2=imagesc(A_discard,'Parent',app.UIAxes2,'ButtonDownFcn',{@image_click2,app});caxis(app.UIAxes2,[0 0.2]);colormap(app.UIAxes2,'hot');
                app.UIAxes2.XLim = [0 app.m2.XData(2)];
                disableDefaultInteractivity(app.UIAxes2);
                if sum(~app.ix)>100
                    app.Slider2.Visible=1;
                    changingValue=app.Slider2.Value;
                    ran=app.m2.YData(1,2)-500;
                    ylim(app.UIAxes2,[(100-changingValue)/100*ran,(100-changingValue)*ran/100+500]);
                else
                    app.Slider2.Visible=0;
                    ylim(app.UIAxes2,[0 500])
                end
            end
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, neuron, ix)
            app.done=0;
            a=full(neuron.A);
            
            d=[neuron.options.d1,neuron.options.d2];
            app.A = extract_patch(a,d,[50,50]);  % extract 50 x 50 patches
            app.ix=ix;  
            app.id=1:size(app.A,4);
            plot_components(app)
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            ran=app.m.YData(1,2)-500; 
            ylim(app.UIAxes,[(100-changingValue)/100*ran,(100-changingValue)*ran/100+500])           
        end

        % Button pushed function: ButtonR
        function ButtonRPushed(app, event)
            app.ix(app.ix)=app.ix_c;
            plot_components(app)
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.done=1;
        end

        % Value changing function: Slider2
        function Slider2ValueChanging(app, event)
            changingValue = event.Value;
            ran=app.m2.YData(1,2)-500; 
            ylim(app.UIAxes2,[(100-changingValue)/100*ran,(100-changingValue)*ran/100+500]); 
            
        end

        % Button pushed function: ButtonL
        function ButtonLPushed(app, event)
            app.ix(~app.ix)=app.ix_inc;
            plot_components(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.done=1;          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.ButtonR = uibutton(app.UIFigure, 'push');
            app.ButtonR.ButtonPushedFcn = createCallbackFcn(app, @ButtonRPushed, true);
            app.ButtonR.Text = '>>';
            app.ButtonL = uibutton(app.UIFigure, 'push');
            app.ButtonL.ButtonPushedFcn = createCallbackFcn(app, @ButtonLPushed, true);
            app.ButtonL.Text = '<<';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Visible = 'off';
            app.Slider.Value = 100;
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done!';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @Slider2ValueChanging, true);
            app.Slider2.Visible = 'off';
            app.Slider2.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manually_classify_spatial(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end